classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        triaxialTestEvaluationTool      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        btnExportGraphs                 matlab.ui.control.Button
        MainExperimentPanel             matlab.ui.container.Panel
        SelectExperimentLabel           matlab.ui.control.Label
        selectExperimentPrimary         matlab.ui.control.DropDown
        TimestepminLabel_2              matlab.ui.control.Label
        selectTimestepPrimary           matlab.ui.control.DropDown
        primaryRockDataTable            matlab.ui.control.Table
        DescriptionLabel                matlab.ui.control.Label
        CommentLabel                    matlab.ui.control.Label
        mainDescription                 matlab.ui.control.TextArea
        mainComment                     matlab.ui.control.TextArea
        ComparisonExperimentPanel_2     matlab.ui.container.Panel
        TimestepminLabel                matlab.ui.control.Label
        selectTimestepSecondary         matlab.ui.control.DropDown
        selectExperimentSecondary       matlab.ui.control.DropDown
        SelectExperimentLabel_2         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        tabGroup                        matlab.ui.container.TabGroup
        experimentsOverviewTab          matlab.ui.container.Tab
        experimentsOverviewTable        matlab.ui.control.Table
        ExperimentsListFilterButton     matlab.ui.control.StateButton
        ExperimentsListLoadSelected     matlab.ui.control.Button
        dataAndOutliersTab              matlab.ui.container.Tab
        dataAndWeightPanel              matlab.ui.container.Panel
        graphOverview                   matlab.ui.control.UIAxes
        graphWeight                     matlab.ui.control.UIAxes
        graphFluidFlow                  matlab.ui.control.UIAxes
        graphDeformation                matlab.ui.control.UIAxes
        timelogTab                      matlab.ui.container.Tab
        dataVsTimelogPanel              matlab.ui.container.Panel
        graphTimelogTab                 matlab.ui.control.UIAxes
        DisplayonrightaxisLabel         matlab.ui.control.Label
        y2AxisTimelogTab                matlab.ui.control.DropDown
        timelogPanel                    matlab.ui.container.Panel
        timelogTableActual              matlab.ui.control.Table
        timelogTablePre                 matlab.ui.control.Table
        timelogTablePost                matlab.ui.control.Table
        clearEventsButton               matlab.ui.control.Button
        DisplayonleftaxisLabel          matlab.ui.control.Label
        y1AxisTimelogTab                matlab.ui.control.DropDown
        hintNoTimelog                   matlab.ui.control.Label
        comparisonTab                   matlab.ui.container.Tab
        MainExperimentPanel_2           matlab.ui.container.Panel
        graphPrimaryComparisonTab       matlab.ui.control.UIAxes
        YAxisLeftLabel_4                matlab.ui.control.Label
        y1AxisPrimaryComparisonTab      matlab.ui.control.DropDown
        XAxisLabel_3                    matlab.ui.control.Label
        xAxisPrimaryComparisonTab       matlab.ui.control.DropDown
        YAxisRightLabel_3               matlab.ui.control.Label
        y2AxisPrimaryComparisonTab      matlab.ui.control.DropDown
        LegendLabel                     matlab.ui.control.Label
        legendPrimaryComparisonTab      matlab.ui.control.DropDown
        ExportData                      matlab.ui.control.Button
        ComparisonExperimentPanel       matlab.ui.container.Panel
        YAxisLeftLabel_3                matlab.ui.control.Label
        y1AxisSecondaryComparisonTab    matlab.ui.control.DropDown
        graphSecondaryComparisonTab     matlab.ui.control.UIAxes
        XAxisLabel_2                    matlab.ui.control.Label
        xAxisSecondaryComparisonTab     matlab.ui.control.DropDown
        YAxisRightLabel_2               matlab.ui.control.Label
        y2AxisSecondaryComparisonTab    matlab.ui.control.DropDown
        LegendLabel_2                   matlab.ui.control.Label
        legendSecondaryComparisonTab    matlab.ui.control.DropDown
        alignX                          matlab.ui.control.Button
        alignY                          matlab.ui.control.Button
        overlappingComparisonTab        matlab.ui.container.Tab
        overlappingComparisonPanel      matlab.ui.container.Panel
        graphOverlappingComparisonTab   matlab.ui.control.UIAxes
        experimentsOverlappingTable     matlab.ui.control.Table
        YAxisLeftLabel_5                matlab.ui.control.Label
        y1AxisOverlappingComparisonTab  matlab.ui.control.DropDown
        XAxisLabel_4                    matlab.ui.control.Label
        xAxisOverlappingComparisonTab   matlab.ui.control.DropDown
        YAxisRightLabel_4               matlab.ui.control.Label
        y2AxisOverlappingComparisonTab  matlab.ui.control.DropDown
        LegendLabel_3                   matlab.ui.control.Label
        legendOverlappingComparisonTab  matlab.ui.control.DropDown
        RefreshGraphButton              matlab.ui.control.Button
        FigureSettingsButton            matlab.ui.control.StateButton
        StatisticsTextAreaLabel         matlab.ui.control.Label
        StatisticsResults               matlab.ui.control.TextArea
        EndSliderLabel                  matlab.ui.control.Label
        EndSlider                       matlab.ui.control.Slider
        StartSliderLabel                matlab.ui.control.Label
        StartSlider                     matlab.ui.control.Slider
        startSliderValue                matlab.ui.control.EditField
        endSliderValue                  matlab.ui.control.EditField
        ExperimentsMenu                 matlab.ui.container.ContextMenu
        SelectFilterMenu                matlab.ui.container.Menu
        FigureMenu                      matlab.ui.container.ContextMenu
        SettingsMenu                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
         db % Database connection
         
         %variables for primary experiment loaded
         experimentNoPrimary
         
		 
         %variables for secondary experiment loaded
         experimentNoSecondary
         
         Analytics_timeStep %new Table (chosen timestep)
         flowMassOutliers %flowMass outliers graph
         plotCorrectedGraphWeight %corrected flowMass graph 
         
         loadingProgBar % Progressbar while loading or calculating
         
         %variables for dropdown menus
         y1PlotTimelogTab
         y2PlotTimelogTab
         y1PlotPrimaryComparisonTab
         y2PlotPrimaryComparisonTab
         y1PlotSecondaryComparisonTab
         y2PlotSecondaryComparisonTab
         y1PlotPrimaryComparisonTab2
         y2PlotPrimaryComparisonTab2
         y1PlotSecondaryComparisonTab2
         y2PlotSecondaryComparisonTab2
         xPlotPrimaryComparisonTab2
         xPlotSecondaryComparisonTab2
         
         %variables containing the graphs
         strainSensorsMeanGraph
         fluidPressureGraph
         hydraulicCylinderGraph
         confiningPressureGraph
         flowMassGraph
         flowMassDiffGraph
         strainSensor1MeanDeltaGraph
         strainSensor2MeanDeltaGraph
         
         %creating a list of all graphs
         listGraphs
         plotProperties
		 plotPropertiesColorMapName
         
         timelog %timelog table
         
         %permeability_timeStep %permeability of selected timestep
         plotline %Line plot to mark timelog events
         plottext %number of timelog event
		 
		 dialogApps = struct % Additional apps for filter options and other dialogs
		 
		 experimentOverviewOriginal  % Table containing the original experiment list (needed for filtering)
    end
    
    methods (Access = private)
        
		function wrapLabelText(label, txt)
			% Create a uicontrol whose text will look like that of the label.
			h = uicontrol( ...
			'Style', 'Text', ...
			'Parent', figure('Visible', 'off'), ... % Make sure the containing figure is invisible.
			'Position', label.Position, ...
			'FontUnits', 'pixels', ... % By default App Designer uses 'pixels' but uicontrol uses 'points'. Define before the FontSize!
			'FontSize', label.FontSize, ...
			'FontName', label.FontName, ...
			'FontAngle', label.FontAngle, ...
			'FontWeight', label.FontWeight, ...
			'HorizontalAlignment', label.HorizontalAlignment ...
			);
		
			% Determine where the text will be wrapped.
			outtext = textwrap(h, {txt});
			delete(h);
		
			% Assign the text to the label.
			label.Text = outtext;
		end
		
		
		function result = updateExperimentsOverlappingTable(app)
		% Cheking loaded experiments and list them on merge experiments tab in experiments overview table. Each
		% new experiment will be activated to be ploted. On list update all checkboxes states will be kept.
			
			experimentList = {};
			
			for experiment_no = app.db.getLoadedExperiments
				experiment = {true experiment_no app.db.experiment(experiment_no).metaData.short};
				experimentList = vertcat(experimentList, experiment);
			end
			
			try
				experimentListLength = size(experimentList);
				tableLength = size(app.experimentsOverlappingTable.Data);
				if tableLength > 0
					for i=1:experimentListLength(1)
						for k=1:tableLength(1)
							
							if cell2mat(app.experimentsOverlappingTable.Data(k,2)) == cell2mat(experimentList(i,2))
								experimentList(i,1) = app.experimentsOverlappingTable.Data (k,1);
							end
							
						end
					end
				end
				
			catch E
			end
			
			
			app.experimentsOverlappingTable.Data = experimentList;
		end
		
		
        function result = export2csv(app, experimentNo, xValue, y1Value, y2Value, timestep)
        %Function used for exporting graph data to an csv-file.
        
            if nargin < 6 || nargin > 6
                error([class(app), ' - ', 'Not enough or too many input arguments.']);
            end
            
            % Stop function execution when no value is submitted!
            if (isempty(xValue) || strcmp(xValue, 'none')) && (isempty(y1Value) || strcmp(y1Value, 'none')) && (isempty(y2Value) || strcmp(y2Value, 'none'))
                return;
            else
            
                try
                    [file, path] = uiputfile('*.csv','File Selection','export.csv'); %Set save-path
                    
                    graphData = app.db.getGraphData(experimentNo, xValue, y1Value, y2Value, timestep); %Get graph dataset
                    
					graphData.dataset.Properties.RowTimes.Format = 'yyyy-MM-dd HH:mm:ss';
					graphData.dataset.Properties.VariableNames('x') = {graphData.x.label};
					graphData.dataset.Properties.VariableNames('y1') = {graphData.y1.label};
					
					if isfield(graphData,'y2')
						graphData.dataset.Properties.VariableNames('y2') = {graphData.y2.label};
					end
					
                    writetimetable(graphData.dataset,[path file], 'FileType', 'text') %Write table to csv file
                    
                    uialert(app.triaxialTestEvaluationTool, 'File export succeeded', 'File Export Succeeded', 'Icon', 'success');
                    result = true;
                catch E
                    warning()
                    uialert(app.triaxialTestEvaluationTool, ['File export failed. ', E.message], 'Warning', 'Icon', 'warning');
                    result = false;
                    
                end
                
            end    
                
        end
        
        function result = exportGraphs(app)
            
            % Set default values
            exportResolution = 600;
            validExtensions = ["png"; "eps"; "pdf"];
            fExtensionList = [];
            
            for extension = validExtensions
                fExtensionList = [fExtensionList; strcat('*.', extension)];
            end
            
            try
                [fName, fPath] = uiputfile(fExtensionList);
                
                % Show please wait bar
                titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is working ...');
                messageText = strcat('Exporting, please wait...');
                loadingProgBar_2 = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                
                % Check if set file is valid
                if isequal(fPath, 0) || isequal(fName, 0)
                    error([class(app), ' - ', 'No file/folder for export selected.'])
                end
                
                [~, fName, fExtension] = fileparts(fName);
                
                % Check if extension is part of list of valid extensions
                if ~ismember(extractAfter(fExtension, 1), validExtensions)
                    error([class(app), ' - ', strcat('Invalid file extentsion: ', fExtension)])
                end
                
                % Iterate over all graphs
                preText = [];
                for graph = app.listGraphs
                    graphTitle = graph.Title.String;
                    
                    % If graph has no title, axis labels will be used
                    if isempty(graphTitle) ||isequal(graphTitle, '') || isequal(graphTitle, 'Title')
                        xLabel = extractBefore(graph.XLabel.String, " ");
                        yLabel = extractBefore(graph.YLabel.String, " ");
                        if ~isempty(xLabel) && ~isempty(yLabel)
                            graphTitle = strcat(xLabel, "-", yLabel);
                        else
                            preText = [preText strcat("   ", " ...empty graph ignored")];
                            continue;
                        end
                    end


                    filename = strcat(fName, '_', lower(graphTitle), fExtension);
                    exportFile = fullfile(fPath, filename);
                    
                    nowText = strcat("   ", filename," ...in progress");
                    messageText = ["Exporting, please wait..." preText nowText];
                    loadingProgBar_2 = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');                    
                    
                    exportgraphics(graph, exportFile, 'Resolution', exportResolution, 'BackgroundColor','none')
                    
                    preText = [preText strcat("   ", filename," ...done")];
                end
                
                uialert(app.triaxialTestEvaluationTool, ["Graph export successful." " " preText], 'Export successful', 'Icon', 'success');
                result = true;
                return;
            
            catch E
                close(loadingProgBar_2);
                uialert(app.triaxialTestEvaluationTool, ['An error occured while graph export: ', ...
                    E.message], 'error');
                result = false;
                return;
            end
        end
        
        
        function result = setGraphDataSet(app, experimentNo, xValue, y1Value, y2Value, timestep, graphVar, graphClear, legendPosition)
        %Function to plot graphs with given datasets into given graphs
        %depending on the handed over selected dataset (selected via
        %dropdown).
            
            if nargin < 7 || nargin > 9
                error([class(app), ' - ', 'Not enough or too many input arguments.']);
            elseif nargin == 7
                graphClear = false;
				legendPosition = 'best';
			elseif nargin == 8
				legendPosition = 'best'; 
            end
			
			% Check if graphVar is of type graph
			if ~isa(graphVar, 'matlab.ui.control.UIAxes')
				error([class(app), ' - ', 'graphVar needs to be of type "matlab.ui.control.UIAxes"']);
			end
			
			% Check if legend position is valid
			if ~strcmp(legendPosition, 'northwest') && ~strcmp(legendPosition, 'best') && ~strcmp(legendPosition, 'northeast') ...
					&& ~strcmp(legendPosition, 'southwest') && ~strcmp(legendPosition, 'southeast') ...
					&& ~strcmp(legendPosition, 'north') && ~strcmp(legendPosition, 'south') ...
					&& ~strcmp(legendPosition, 'west') && ~strcmp(legendPosition, 'east')
				warning([class(app), ' - ', legendPosition,' is an invalid legend position. Using default: southeast']);
			end
				
            % Stop function execution when no value is submitted!
            if (isempty(xValue) || strcmp(xValue, 'none')) || (isempty(y1Value) || strcmp(y1Value, 'none')) && (isempty(y2Value) || strcmp(y2Value, 'none'))
                return;
            else    
				
				%Clear graph
				if graphClear
				   cla(graphVar,'reset');
				   graphVar.NextPlot = 'add';
				end
            
				% Check if experimentNo is a single value or a list of values
				if length(experimentNo) == 1
				
					try
						%Show please wait bar
						titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is working ...');
						messageText = strcat('Updating graph, please wait...');
						loadingProgBar_2 = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
						
						graphData = app.db.getGraphData(experimentNo, xValue, y1Value, y2Value, timestep);
						
						%Set plot marker to none as default
						y1Marker = 'none';
						y2Marker = 'none';
						
						%Replace NaN by [] to have a plotted line
						graphDataNoNaN = graphData;
						graphDataNoNaN.dataset = fillmissing(graphData.dataset, 'linear', 'EndValues', 'none');
						
						if ~isempty(y1Value) && ~strcmp(y1Value, 'none')
							
							yyaxis(graphVar, 'left');
							switch  xValue  
								case 'runtime'
									plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y1, 'DurationTickFormat', graphData.x.unit, 'DisplayName', graphData.y1.legend);
									
								otherwise
									plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y1, 'DisplayName', graphDataNoNaN.y1.legend);
							end 
							
							graphVar.YAxis(1).Color = app.plotProperties.lineColor('left');
							plotVar.Color = app.plotProperties.lineColor('left');
							plotVar.LineStyle = app.plotProperties.lineStyle('left');
							plotVar.Marker = y1Marker;
							graphVar.XAxis.Label.String = graphDataNoNaN.x.legend;
							graphVar.YAxis(1).Label.String = graphDataNoNaN.y1.legend;
							legend(graphVar,'-DynamicLegend');
							graphVar.Legend.Title.String = [regexprep(app.db.getMetaData(experimentNo).short,'_','\\_'), ' (#',  int2str(experimentNo),')'];
							graphVar.Legend.Location = legendPosition;

						end
						
						if ~isempty(y2Value) && ~strcmp(y2Value, 'none')
							
							yyaxis(graphVar, 'right');
							switch  xValue  
								case 'runtime'
									plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y2, 'DurationTickFormat', graphDataNoNaN.x.unit, 'DisplayName', graphDataNoNaN.y2.legend);
									
								otherwise
									plotVar = plot(graphVar, graphDataNoNaN.dataset.x, graphDataNoNaN.dataset.y2, 'DisplayName', graphDataNoNaN.y2.legend);
							end
							
							graphVar.YAxis(2).Color = app.plotProperties.lineColor('right');
							plotVar.Color = app.plotProperties.lineColor('right');
							plotVar.LineStyle = app.plotProperties.lineStyle('right');
							plotVar.Marker = y2Marker;
							graphVar.YAxis(1).Label.String = graphDataNoNaN.y2.legend; 
							graphVar.YAxis(2).Visible = true;
							
						else
							
							graphVar.YAxis(2).Visible = false; %hide right yaxis
						end
						
						%Check if one of the datasets is smaller then the other
						if sum(strcmp('y1',graphData.dataset.Properties.VariableNames))
							if length(graphData.y1.data) < length(graphData.x.data)
								
								yyaxis(graphVar, 'left');
								switch  xValue  
									case 'runtime'
										plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y1, 'DurationTickFormat', graphData.x.unit, 'DisplayName', graphData.y1.legend);
										
									otherwise
										plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y1, 'DisplayName', graphData.y1.legend);
								end 
								
								plotVar.Color = app.plotProperties.lineColor('left');
								plotVar.LineStyle = 'none';
								plotVar.Marker = app.plotProperties.marker('left');
							end
						end
						
						if sum(strcmp('y2',graphData.dataset.Properties.VariableNames))
							if length(graphData.y2.data) < length(graphData.x.data)
								
								yyaxis(graphVar, 'right');
								switch  xValue  
									case 'runtime'
										plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y2, 'DurationTickFormat', graphData.x.unit, 'DisplayName', graphData.y2.legend);
										
									otherwise
										plotVar = plot(graphVar, graphData.dataset.x, graphData.dataset.y2);
								end 
								
								plotVar.Color = app.plotProperties.lineColor('right');
								plotVar.LineStyle = 'none';
								plotVar.Marker = app.plotProperties.marker('right');
								
							end
						end
						
						result = true;
						close(loadingProgBar_2);
						
					catch E
						close(loadingProgBar_2);
						uialert(app.triaxialTestEvaluationTool, ['An error occured while loading a new dataset for the selected graph: ' ...
							'#', int2str(experimentNo), '; ', ...
							'x: ', xValue, '; ', ...
							'y1: ', y1Value, '; ', ...
							'y1: ', y2Value, '; ',...
							int2str(timestep),  ' min; ', E.message], 'warning', 'Icon', 'warning');
						result = false;
						return;
					end
					
					
				elseif length(experimentNo) > 1
					
					try
						%Show please wait bar
						titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is working ...');
						messageText = strcat('Updating graph, please wait...');
						loadingProgBar_2 = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
						
						% get colormap depending on plotPropertiesColorMapName and number of lines
						cmap = brewermap(size(app.experimentsOverlappingTable.Data,1), app.plotPropertiesColorMapName);
						colormap(graphVar, cmap);
						
						graphData = table();
						
						
						for k=1:length(experimentNo)
							%Replace NaN by [] to have a plotted line
							graphDataTemp = app.db.getGraphData(experimentNo(k), xValue, y1Value, y2Value, timestep);
							graphDataTemp.experimentNo = experimentNo(k);
							tempTable = table(graphDataTemp);
							tempTable(1,1).graphDataTemp.dataset = fillmissing(tempTable(1,1).graphDataTemp.dataset, 'linear', 'EndValues', 'none');
							graphData = [graphData; tempTable];
						end		
						
						%Set plot marker to none as default
						y1Marker = 'none';
						y2Marker = 'none';
						
						%Clear graph
						if graphClear
						   cla(graphVar,'reset');
						   graphVar.NextPlot = 'add';
						end
						
						if ~isempty(y1Value) && ~strcmp(y1Value, 'none')
							
							yyaxis(graphVar, 'left');
							
							tableLength = size(app.experimentsOverlappingTable.Data);
							
							for j=1:tableLength(1)
								for k=1:length(experimentNo)
								
									if cell2mat(app.experimentsOverlappingTable.Data(j,2)) == graphData(k,1).graphDataTemp.experimentNo
										if cell2mat(app.experimentsOverlappingTable.Data(j,1))
											
											expNo = graphData(k,1).graphDataTemp.experimentNo;
											shortName = app.db.getMetaData(expNo).short;
											xData = graphData(k,1).graphDataTemp.dataset.x;
											xUnit = graphData(k,1).graphDataTemp.x.unit;
											xLegend = graphData(k,1).graphDataTemp.x.legend;
											yData = graphData(k,1).graphDataTemp.dataset.y1;
											yLegend = graphData(k,1).graphDataTemp.y1.legend;
										
											switch  xValue  
												case 'runtime'
													plotVar = plot(graphVar, xData, yData, 'DurationTickFormat', xUnit, 'DisplayName', regexprep(shortName,'_','\\_'));
													
												otherwise
													plotVar = plot(graphVar, xData, yData, 'DisplayName', regexprep(shortName,'_','\\_'));
											end
											
											plotVar.Color = cmap(j,:);
											plotVar.LineStyle = app.plotProperties.lineStyle('left');
											plotVar.Marker = y1Marker;

										end
									end
								end
							end

							graphVar.YAxis(1).Color = 'black';
							graphVar.XAxis.Label.String = xLegend;
							graphVar.YAxis(1).Label.String = yLegend;
							
							%create a subset of created lines for later creation of a legend
							plotVarSubset = graphVar.Children;

						end
						
						if ~isempty(y2Value) && ~strcmp(y2Value, 'none')
							
							yyaxis(graphVar, 'right');
							hold(graphVar, 'on')  % hold graph
							
							tableLength = size(app.experimentsOverlappingTable.Data);
							
							for j=1:tableLength(1)
								for k=1:length(experimentNo)
								
									if cell2mat(app.experimentsOverlappingTable.Data(j,2)) == graphData(k,1).graphDataTemp.experimentNo
										if cell2mat(app.experimentsOverlappingTable.Data(j,1))
											
											expNo = graphData(k,1).graphDataTemp.experimentNo;
											shortName = app.db.getMetaData(expNo).short;
											xData = graphData(k,1).graphDataTemp.dataset.x;
											xUnit = graphData(k,1).graphDataTemp.x.unit;
											xLegend = graphData(k,1).graphDataTemp.x.legend;
											yData = graphData(k,1).graphDataTemp.dataset.y2;
											yLegend = graphData(k,1).graphDataTemp.y2.legend;
										
											switch  xValue  
												case 'runtime'
													plotVar = plot(graphVar, xData, yData, 'DurationTickFormat', xUnit, 'DisplayName', regexprep(shortName,'_','\\_'));
													
												otherwise
													plotVar = plot(graphVar, xData, yData, 'DisplayName', regexprep(shortName,'_','\\_'));
											end
											
											plotVar.Color = cmap(j,:);
											plotVar.LineStyle = app.plotProperties.lineStyle('right');
											plotVar.Marker = y1Marker;
										end	
									end
								end
							end

							graphVar.YAxis(2).Label.String = yLegend;
							graphVar.YAxis(2).Color = 'black'; 
							graphVar.YAxis(2).Visible = true;
						else
							graphVar.YAxis(2).Visible = false; %hide left yaxis
							
						end
						
						%Create legend
						legend(plotVarSubset);
						graphVar.Legend.Location = legendPosition;
						
						result = true;
						close(loadingProgBar_2);
						
					catch E
						close(loadingProgBar_2);
						uialert(app.triaxialTestEvaluationTool, ['An error occured while loading a new dataset for the selected graph: ' ...
							'#', int2str(experimentNo), '; ', ...
							'x: ', xValue, '; ', ...
							'y1: ', y1Value, '; ', ...
							'y1: ', y2Value, '; ',...
							int2str(timestep),  ' min; ', E.message], 'warning', 'Icon', 'warning');
						result = false;
						return;
					end
					
					
				end
            end
            
        end
        
        function showTimelogTick(app, graphHandle, tableHandle, row)
        %Showing timelog events in a given graph. Handle for the graph and the timelog table have to be input.
        %The row containing the information is an input parameter as well.
            try 
                yLimit = graphHandle.YLim; %add row number in plot
                
                isChecked = tableHandle.Data{row, 1}; %finds checked boxes
                
                %check if an event is checked or unchecked
                if isChecked
                    runtimeEvent = tableHandle.Data{row, 2}; %find runtime
                    
                    %alternating colors and position of numbering
                    if mod(row,2)
                        color = 'green';
                        position = 0.9;
                    else
                        color = 'magenta';
                        position = 0.85;
                    end
                    
                    %plot vertical line
                    app.plotline(row) = plot(graphHandle, [runtimeEvent runtimeEvent], [yLimit(1,1) yLimit(1,2)], 'm-', 'HandleVisibility', 'off', 'Color', color);
                    
                    %plot row number
                    txt = num2str(row);
                    v = ones(size(runtimeEvent)) * (yLimit(1,2) * position); %location of number y axis (80% of maximum y-axes)
                    app.plottext(row) = text(graphHandle, runtimeEvent, v, txt, 'FontSize', 14, 'FontWeight', 'bold');
                    
                else
                    %Try to delete existing rows
                    try
                        delete(app.plotline(row))
                        delete(app.plottext(row))
                    catch
                        
                    end
            
                end 
            catch E
                uialert(app.triaxialTestEvaluationTool, ['An error occured while graph export: ', ...
                    E.message], 'error');
            end
        end


        function results = getStatistics(app, experimentNo, xValue, y1Value, y2Value, timestep, graphHandle)
			startValue = app.StartSlider.Value;
			endValue= app.EndSlider.Value;
			
			statStart = 1000001;
			statEnd = 1000002;
			
			if startValue == app.StartSlider.Limits(1) && endValue == app.EndSlider.Limits(2)
				results = true;
				return;
			end
			
			if (isempty(xValue) || strcmp(xValue, 'none')) || (isempty(y1Value) || strcmp(y1Value, 'none')) && (isempty(y2Value) || strcmp(y2Value, 'none'))
                return;
			end
			
			if startValue > endValue
				% swap start and end
				app.StartSlider.Value = endValue;
				app.EndSlider.Value = startValue;
				
				startValue = app.StartSlider.Value;
				endValue= app.EndSlider.Value;
			end

			startRange = app.StartSlider.Limits(2) - app.StartSlider.Limits(1);
			endRange = app.EndSlider.Limits(2) - app.EndSlider.Limits(1);
			
			try 
                yLimit = graphHandle.YLim;
				xLimit = graphHandle.XLim;
				
				%Try to delete existing rows
				try
					delete(app.plotline(statStart))
					delete(app.plottext(statEnd))
				catch
					
				end
				
				startValue = xLimit(2)/startRange*startValue;
				endValue = xLimit(2)/endRange*endValue;
				
				%plot vertical line
				app.plotline(statStart) = plot(graphHandle, [startValue startValue], [yLimit(1,1) yLimit(1,2)], 'm-', 'HandleVisibility', 'off', 'Color', 'green');
				app.plotline(statEnd) = plot(graphHandle, [endValue endValue], [yLimit(1,1) yLimit(1,2)], 'm-', 'HandleVisibility', 'off', 'Color', 'red');
			catch E
                uialert(app.triaxialTestEvaluationTool, ['An error occured while addind lines to graph: ', ...
                    E.message], 'error');
			end
			
			text = [];
			
			for k=1:length(experimentNo)
						
				graphData = app.db.getGraphData(experimentNo(k), xValue, y1Value, y2Value, timestep);
				
				text = sprintf([text '## Nr. ' sprintf('%g', experimentNo(k)) '\n']);
				
				dataset = graphData.dataset;
				
				dataset(dataset.x<startValue,:) = [];
				dataset(dataset.x>endValue,:) = [];
				
				if ismember('y1', dataset.Properties.VariableNames)
					
					meanVar = mean(dataset.y1);
					stdVar = std(dataset.y1);
					
					maxVar = max(dataset.y1);
					minVar = min(dataset.y1);
					
					text = sprintf([text y1Value '\n  mean:\t' sprintf('%.4g',meanVar) '\n  std:\t\t' sprintf('%.4g',stdVar) '\n  max:\t' sprintf('%.4g',maxVar) '\n  min:\t\t' sprintf('%.4g',minVar) '\n']);
					
				end
				
				if ismember('y2', dataset.Properties.VariableNames)
					
					meanVar = mean(dataset.y2);
					stdVar = std(dataset.y2);
					
					maxVar = max(dataset.y2);
					minVar = min(dataset.y2);
					
					text = sprintf([text y2Value '\nmean:\t' sprintf('%.4g',meanVar) '\nstd:\t' sprintf('%.4g',stdVar) '\nmax:\t' sprintf('%.4g',maxVar) '\nmin:\t' sprintf('%.4g',minVar) '\n']);
				end
			end
			
			app.StatisticsResults.Value = text;
			
			results = true;
            
        end
    end
    
    methods (Access = public)
        
        function results = markLoadedExperiments(app)
			% Reset style of experiments overview table
			removeStyle(app.experimentsOverviewTable)
			app.experimentsOverviewTable.Data.loadList = false(height(app.experimentsOverviewTable.Data),1);
			
			loadedExperiments = app.db.getLoadedExperiments;
			
			% Mark actual experiment in experiments overview table
			tableStyle = uistyle('BackgroundColor',	[198/255 224/255 180/255]);
			for k=1:height(app.experimentsOverviewTable.Data)
				for i=1:length(loadedExperiments)
					if app.experimentsOverviewTable.Data.experimentNo(k) == loadedExperiments(i)
						addStyle(app.experimentsOverviewTable, tableStyle, 'row', k)
					end
				end
			end
			
			results = true;
           
			% Update experiments table on merge tab
			app.updateExperimentsOverlappingTable()
			
			% Show merge tab
			if ~isempty(loadedExperiments)
				app.overlappingComparisonPanel.Visible = 'on';
			end
			
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Function that is called after gui components are initialized. It
        %develops the connection to the database and loads a list of all
        %available experiments.
        %All dropdown menus will be initialized and filled with data,
        %especcialy the dropdown menu to choose an experiment.
        
        app.triaxialTestEvaluationTool.Name = strcat('Triaxial Test Evaluation Tool v2.8');
        
        %Load submodule water-properties
        try
            thisPath = fileparts(which([mfilename('class'),'.mlapp'])); %Get the folder of the actual class file
            submodulePath = [thisPath, '/classes'];
            addpath(submodulePath) %Add sobmodule path
        catch E
            error([class(app), ' - ', 'Error while loading subclasses (', E.message, ')']);
        end
        
        import MeridDB.*
            
            %Show please wait bar
            titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is working ...');
            messageText = strcat('Initializing, please wait...');
            app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
        
            %Initialize property-table for plots/graphs
            app.plotProperties = table('Size', [2,3], 'VariableTypes', {'string', 'string', 'string'}, 'RowNames', {'left';'right'}, 'VariableNames',{'lineColor','marker','lineStyle'});
            app.plotProperties.lineColor('left') = '#0072BD';
            app.plotProperties.marker('left') = 'x';
            app.plotProperties.lineStyle('left') = '-';
            app.plotProperties.lineColor('right') = '#D95319';
            app.plotProperties.marker('right') = 'o';
            app.plotProperties.lineStyle('right') = '-.';
            
			app.plotPropertiesColorMapName = 'dark2'; %'paired';
						
            %Initialize database
            try
                app.db = TriaxTestHandler;
            catch E
                close(app.loadingProgBar);
                uialert(app.triaxialTestEvaluationTool, {'Initialization of connection to database failed. Please restart this tool!', '', E.message}, 'Handler initialization error', 'CloseFcn', 'closereq()');
            end
            
            %Request a list of all experiments and print out in table
                %Request list of experiments				
                experimentOverview = app.db.getExperimentOverview;
				experimentOverview.loadList = false(height(experimentOverview),1); % logical(zeros(height(experimentOverview),1));
                experimentOverview.experimentNo = single(experimentOverview.experimentNo); %Convert experiment number to single
                
                %Format datetime-variables
                experimentOverview.timeStart.Format = 'dd.MM.uu HH:mm';
				experimentOverview.timeStart.TimeZone = '+0100';
                experimentOverview.timeEnd.Format = 'dd.MM.uu HH:mm';
				experimentOverview.timeEnd.TimeZone = '+0100';
                experimentOverview.duration = experimentOverview.timeEnd - experimentOverview.timeStart;
				
				app.experimentOverviewOriginal = experimentOverview;
				
				% Rearange table after adding duration
				experimentOverview = experimentOverview(:,[8 1 2 3 4 9 5]);
				
				
                %Output
                app.experimentsOverviewTable.Data = experimentOverview;
                app.experimentsOverviewTable.ColumnName = {'', '#','Short','Begin','End', 'Duration', 'Assistant'};
            
            %Initialization of dropdowns
                %Experiment-selection dropdown
                dropDownListText = ['- no selection -'; strcat(string(experimentOverview.experimentNo), ':', string(experimentOverview.short))];
                dropDownListData = [0; experimentOverview.experimentNo];
                app.selectExperimentPrimary.Items = dropDownListText;
                app.selectExperimentPrimary.ItemsData = dropDownListData;
				app.selectExperimentPrimary.Value = 0;
                app.selectExperimentPrimary.Tooltip='The permeability will be calculated using a default value of 5 minutes. To select a different timestep, use the Dropdown on the "Results" page.';
                app.xAxisPrimaryComparisonTab.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                app.xAxisSecondaryComparisonTab.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                
                app.selectExperimentSecondary.Items = app.selectExperimentPrimary.Items;
                app.selectExperimentSecondary.ItemsData = app.selectExperimentPrimary.ItemsData;
				app.selectExperimentSecondary.Value = 0;
            
                %Permeability timestep-selection dropdown
                app.selectTimestepPrimary.Items = ['- no selection -'; string([1; 2; 5; 10; 15; 20; 25; 30; 45; 60])];
                app.selectTimestepPrimary.ItemsData = [0; 1; 2; 5; 10; 15; 20; 25; 30; 45; 60];
                app.selectTimestepSecondary.Items = app.selectTimestepPrimary.Items;
                app.selectTimestepSecondary.ItemsData = app.selectTimestepPrimary.ItemsData;
            
                %Dataset-selection dropdown for graphs
                dropDownListText = horzcat('- no selection -', app.db.getLabelList.values);
                dropDownListData = horzcat('none', app.db.getLabelList.keys);                
                
                % app.y2AxisPrimaryComparisonTab.Items = dropDownListText;
                % app.y2AxisPrimaryComparisonTab.ItemsData = dropDownListData;
                % app.y2AxisSecondaryComparisonTab.Items = dropDownListText;
                % app.y2AxisSecondaryComparisonTab.ItemsData = dropDownListData;
                app.y2AxisTimelogTab.Items = dropDownListText;
                app.y2AxisTimelogTab.ItemsData = dropDownListData;
                app.y1AxisTimelogTab.Items = dropDownListText;
                app.y1AxisTimelogTab.ItemsData = dropDownListData;
                % app.y1AxisPrimaryComparisonTab.Items = dropDownListText;
                % app.y1AxisPrimaryComparisonTab.ItemsData = dropDownListData;
                % app.y1AxisSecondaryComparisonTab.Items = dropDownListText;
                % app.y1AxisSecondaryComparisonTab.ItemsData = dropDownListData;
                app.y1AxisPrimaryComparisonTab.Items = dropDownListText;
                app.y1AxisPrimaryComparisonTab.ItemsData = dropDownListData;
                app.y2AxisPrimaryComparisonTab.Items = dropDownListText;
                app.y2AxisPrimaryComparisonTab.ItemsData = dropDownListData;
                app.xAxisPrimaryComparisonTab.Items = dropDownListText;
                app.xAxisPrimaryComparisonTab.ItemsData = dropDownListData;
                app.y1AxisSecondaryComparisonTab.Items = dropDownListText;
                app.y1AxisSecondaryComparisonTab.ItemsData = dropDownListData;
                app.y2AxisSecondaryComparisonTab.Items = dropDownListText;
                app.y2AxisSecondaryComparisonTab.ItemsData = dropDownListData;
                app.xAxisSecondaryComparisonTab.Items = dropDownListText;
                app.xAxisSecondaryComparisonTab.ItemsData = dropDownListData;
				app.y1AxisOverlappingComparisonTab.Items = dropDownListText;
                app.y1AxisOverlappingComparisonTab.ItemsData = dropDownListData;
                app.y2AxisOverlappingComparisonTab.Items = dropDownListText;
                app.y2AxisOverlappingComparisonTab.ItemsData = dropDownListData;
                app.xAxisOverlappingComparisonTab.Items = dropDownListText;
                app.xAxisOverlappingComparisonTab.ItemsData = dropDownListData;
            
                % Initialize list of graphs
                app.listGraphs = [app.graphOverview, app.graphWeight, app.graphFluidFlow, app.graphDeformation, app.graphTimelogTab, app.graphPrimaryComparisonTab, app.graphSecondaryComparisonTab];
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %This part should be put into another function and should be
            %handeld by the tabs and ther children
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %panel visibility is off for all panels before calculation 
            app.selectExperimentSecondary.Enable = 'off';
            app.selectTimestepPrimary.Enable = 'off';
            app.dataAndWeightPanel.Visible = 'off';
            app.dataVsTimelogPanel.Visible = 'off';
            app.graphPrimaryComparisonTab.Visible = 'off';
            % app.comparePanel.Visible = 'off';
            app.MainExperimentPanel_2.Visible = 'off';
            app.ComparisonExperimentPanel.Visible = 'off';
			app.overlappingComparisonPanel.Visible = 'off';
            app.selectExperimentPrimary.Enable = 'on';
			app.ExperimentsListLoadSelected.Enable = true;
			app.ExperimentsListFilterButton.Enable = true;
            close(app.loadingProgBar);
    
        end

        % Callback function
        function displayOutliersWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the flowMass on the scale.
        
            %display outliers for flowMass
            checkboxValue = app.displayOutliersWeight.Value;
            
            if checkboxValue == 1 
                TF = isoutlier(app.experimentsDataPrimary.flowMassDiff, 'mean');
                x = app.experimentsDataPrimary.runtime;
                y = app.experimentsDataPrimary.flowMassDiff;
                
                yyaxis(app.graphWeight, 'right')
                app.flowMassOutliers = plot(app.graphWeight, x(TF), y(TF), 'rx', 'HandleVisibility', 'off');
            
            else
                delete(app.flowMassOutliers)
                
            end
         
        end

        % Callback function
        function correctedGraphWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the flowMass on the scale.
        
            %display corrected flowMass curve     
            checkboxValue = event.Value;
                
            if checkboxValue == 1
                %delete current flowMass diff graph
                delete(app.flowMassDiffGraph)
                delete(app.flowMassOutliers)
                app.displayOutliersWeight.Value = 0;
                x = app.permeabilityDataPrimary.runtime;
                y = app.permeabilityDataPrimary.flowMassDiff;
                yyaxis(app.graphWeight, 'right')
                app.plotCorrectedGraphWeight = plot(app.graphWeight, x, y, 'm-', 'HandleVisibility', 'off');
               
            else %delete plot if box is unchecked
                delete(app.plotCorrectedGraphWeight)
                %plot original flowMassDiff graph
                app.flowMassDiffGraph= app.changeGraphDataSet(...
                    'primary', ...
                    'flowMassDiff', ...
                    app.flowMassDiffGraph, ...
                    app.graphWeight, ...
                    'right','runtime');
                  
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.triaxialTestEvaluationTool.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: y1AxisTimelogTab, y2AxisTimelogTab
        function graphTimelogTabReload(app, event)
            
            y1Selection = app.y1AxisTimelogTab.Value;
            y2Selection = app.y2AxisTimelogTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection, app.graphTimelogTab, true);
            
            %Respect ticks from the timelog
            if ~isempty(app.timelogTableActual.Data)
                for k = 1 : height(app.timelogTableActual.Data)
                    
                    %Check which timelog is ticked
                    if app.timelogTableActual.Data{k, 1}
                        app.showTimelogTick(app.graphTimelogTab, app.timelogTableActual, k);
                    end
                end
            end
        
        end

        % Value changed function: selectTimestepPrimary
        function primaryTimestepChange(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestepPrimary.Value == 0)
                uialert(app.triaxialTestEvaluationTool,'No timestep was selected.','Warning', 'Icon', 'warning');
                return;
            end
            
            app.graphTimelogTabReload();
            app.graphComparisonTabReload_Primary();
            app.graphComparisonTab2Reload_Primary();
        end

        % Value changed function: selectExperimentPrimary
        function selectExperimentNoPrimary(app, event)
        
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperimentPrimary.Value;     
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) || value == 0  %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.triaxialTestEvaluationTool, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNoPrimary = value; 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is working ...');
            messageText = strcat('Loading dataset for experiment no. ', string(app.experimentNoPrimary));
            app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
            
            %Clear graphs and tables and timelog
            cla(app.graphWeight,'reset')
            cla(app.graphDeformation,'reset')
            cla(app.graphFluidFlow,'reset')
            cla(app.graphOverview,'reset')
            cla(app.graphTimelogTab, 'reset')
            cla(app.graphPrimaryComparisonTab, 'reset')
            
            %Reset timelog table
            %empty data in tables
            app.timelogTableActual.Data = [];
            app.timelogTablePre.Data = [];
            app.timelogTablePost.Data = [];
            %add comment
            app.timelogTableActual.ColumnName = {'No timelog available'};
            app.timelogTablePre.ColumnName = {'No timelog available'};
            app.timelogTablePost.ColumnName = {'No timelog available'};
            app.timelogTableActual.ColumnWidth = 'auto';
            app.timelogTablePre.ColumnWidth = 'auto';
            app.timelogTablePost.ColumnWidth = 'auto';
            %remove events button
            app.clearEventsButton.Enable = 'off';
            
            app.timelogTableActual.ColumnName = [];
            app.y2AxisTimelogTab.Value = 'none';
            app.y2AxisPrimaryComparisonTab.Value = 'none';
            
            %Check if the experiment is allready loaded
            if ~app.db.isExperimentLoaded(app.experimentNoPrimary)
                messageText = strcat('Loading dataset for experiment no. ', string(app.experimentNoPrimary), ' from database. Depending on the duration of the experiment this might take a while.');
                app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                if(~app.db.loadExperiment(app.experimentNoPrimary))
                    uialert(app.triaxialTestEvaluationTool, 'Error loading experiments data.', 'Error');
                    close(app.loadingProgBar);
                    return;
                end
            end
            
            %Try to load metadata and specimendata from database und show them in a table
            try
                %Get metadata from database
                messageText = strcat('Loading metadata for experiment no. ', string(app.experimentNoPrimary));
                app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                metaData = app.db.getMetaData((app.experimentNoPrimary));
                
                %Check if metadata was submitted, otherwise exit function
                if (metaData == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialTestEvaluationTool, 'No metadata for selected experiment found.', 'Warning', 'Icon', 'warning');
                    close(app.loadingProgBar);
                    return;
                    
                else
                    app.mainDescription.Value = metaData.description;
                    app.mainComment.Value = metaData.comment;
                end
 
                %Get specimen and rock data from database
                specimenData = app.db.getSpecimenData(app.experimentNoPrimary);
                
                %Check if specimen data was submitted, otherwise exit function
                if (specimenData == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialTestEvaluationTool, 'No specimendata for selected experiment found.', 'warning', 'Icon', 'warning');
                    close(app.loadingProgBar);
                    return;
                end
                
                %Write rockdata to table in GUI
                app.primaryRockDataTable.RowName = []; %clear column property
                app.primaryRockDataTable.Data = specimenData.getDataTable;
                app.primaryRockDataTable.ColumnName = {'Property','Value'};
                
            catch E
                uialert(app.triaxialTestEvaluationTool, ['An error occured while loading specimen- or metadata for the selected experiment.(', E.message, ')'], 'warning', 'Icon', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Display all data';
            app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
            
                  
            %GRAPH OVERVIEW containing all relevant measurements
            %setGraphDataSet(app, experimentNo, xValue, y1Value, y2Value, timestep, graphVar)
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'strainSensor', '', ...
                0, app.graphOverview, true);
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', '', 'confiningPressure', ...
                0, app.graphOverview, false);
                
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', '', 'hydrCylinderPressure', ...
                0, app.graphOverview, false); 
                                        
            title(app.graphOverview, 'Data Overview');

            %GRAPH FLUID FLOW containing fluid pressure
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'fluidPressure', '', ...
                0, app.graphFluidFlow, true); 
                    
            title(app.graphFluidFlow, 'Fluid Flow Data');
            
            %GRAPH WEIGHT containing flowMass and flowMass difference
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'flowMassDiff', 'flowMass', ...
                1, app.graphWeight, true);
                    
            title(app.graphWeight, 'Weigth and Weight Difference'); 
            
            %GRAPH DEFORMATION
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', 'strainSensor', 'strainSensor1', ...
                0, app.graphDeformation, true);
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', '', 'strainSensor2', ...
                0, app.graphDeformation);
            
            title(app.graphDeformation, 'Deformation and Deviation of Sensors');

            
            %make all panels visible
            app.dataAndOutliersTab.HandleVisibility = 'on';
            app.dataAndWeightPanel.Visible = 'on';
            app.dataVsTimelogPanel.Visible = 'on';
            % app.comparePanel.Visible = 'on';
            
            %Plot y1-axis in graph on timetable tab
            %Plot Permeability
            app.selectTimestepPrimary.Value = 2;                
            app.y1AxisTimelogTab.Value = 'permeabilityCoeff';
            app.y1AxisPrimaryComparisonTab.Value = 'permeabilityCoeff';
            app.xAxisPrimaryComparisonTab.Value = 'runtime';
            
            app.graphTimelogTabReload(); 
            
            app.graphComparisonTabReload_Primary();
            app.graphPrimaryComparisonTab.Visible = 'on'; 
            app.selectTimestepPrimary.Enable = 'on';
            app.selectExperimentSecondary.Enable = 'on';
            app.MainExperimentPanel_2.Visible = 'on';
            app.ComparisonExperimentPanel.Visible = 'on';
			app.overlappingComparisonPanel.Visible = 'on';
            app.btnExportGraphs.Enable = 'on';
            
            %set TIMELOG data in table 
            try
                if ~isempty(app.db.getTimelog(app.experimentNoPrimary)) 
                    app.clearEventsButton.Enable = 'on';
                    
                    %use *any* table that has not been retimed ... to find matching runtimes
                    %retime table as all timelog entries have format
                    %HH:MM
                    dataTableTimelog = retime(app.db.getPressureData(app.experimentNoPrimary),'minutely','linear');
                    
                    %Only use timelog entries that match the start and
                    %end times of the data table-- otherwise runtime
                    %cannot be added as array size does not match
                    timerangeTable = timerange(dataTableTimelog.datetime(1), dataTableTimelog.datetime(end), 'closed');
                    app.timelog = app.db.getTimelog(app.experimentNoPrimary); %create timetable
                    % app.timelog.time = dateshift(app.timelog.time, 'start', 'minute', 'nearest');
					app.timelog.time.Format = 'dd.MM.uu HH:mm';
                    app.timelog = app.timelog(timerangeTable,:);
                    app.timelog = timetable2table(app.timelog); %neccessary otherwise markers are not found
                    
                    %Find markers for time log events and add runtime to timelog
                    eventTime = dateshift(app.timelog.time, 'start', 'minute', 'nearest'); % round to minutes
                    markers = dataTableTimelog(eventTime,:); %finds all rows of table corresponding to the datetime 
                    app.timelog.runtime = (markers.runtime); %add runtime to timelog
                    app.timelog.runtime.Format = ('hh:mm');
                    
                    %delete columns and sort table
                    app.timelog = sortrows(app.timelog, 'time', 'ascend'); %sort timelog table
                    app.timelog.time.Format = 'dd-MM-yyyy HH:mm';
                    app.timelog.selectEvents = false(size(app.timelog.description)); %add checkbox column
                    
                    %set row name, colum name and data in main UITable
                    app.timelog = movevars(app.timelog, 'selectEvents', 'Before', 'time');
                    app.timelog = movevars(app.timelog, 'runtime', 'After', 'selectEvents');
                    app.timelog = movevars(app.timelog, 'description', 'After', 'runtime');
                    app.timelog = movevars(app.timelog, 'retrospective', 'After', 'description');
                    app.timelog = movevars(app.timelog, 'time', 'After', 'retrospective');
                    
                    app.timelogTableActual.Data = app.timelog;
                    app.timelogTableActual.ColumnName = {'Show','Runtime','Description','Retrospective', 'Time'};
                    app.timelogTableActual.RowName = 'numbered';
                       
                    %FIND entries outside of experiment timerange
                    timelogRemain = app.db.getTimelog(app.experimentNoPrimary);
                    timelogRemain.time.Format = 'dd.MM.uu HH:mm';
                    
                     
                    if app.db.getMetaData(app.experimentNoPrimary).timeStart > timelogRemain.time(1) %all entries before start time
                        start_of_entries = timelogRemain.time(1);
                        TR1 = timerange(start_of_entries,timelogRemain.time(1),'closed'); %half open intervall
                        
                        % timelogPre = table2timetable(timelogRemain);
                        timelogPre = timelogRemain(TR1,:); %find all rows before start
                        timelogPre.duration = app.db.getMetaData(app.experimentNoPrimary).timeStart - timelogPre.time;
                        timelogPre = timetable2table(timelogPre); %data for UITable must be table
                        
                        timelogPre = movevars(timelogPre, 'duration', 'Before', 'time');
                        timelogPre = movevars(timelogPre, 'description', 'After', 'duration');
                        timelogPre = movevars(timelogPre, 'retrospective', 'After', 'description');
                        timelogPre = movevars(timelogPre, 'time', 'After', 'retrospective');
                              
                    else
                        col1={'No entries before start of the experiment'};
                        timelogPre=table(col1);
                        app.timelogTablePre.ColumnWidth='auto';
                        app.timelogTablePre.ColumnName={'No Timelog'};
                    end
                    
                    if app.db.getMetaData(app.experimentNoPrimary).timeEnd < timelogRemain.time(end) %all entries after end of experiment
                        TR2=timerange(app.db.getMetaData(app.experimentNoPrimary).timeEnd,timelogRemain.time(end),'closed');  
                        
                        timelogPost=timelogRemain(TR2,:); %find all rows before start
                        timelogPost.duration = timelogPost.time - app.db.getMetaData(app.experimentNoPrimary).timeEnd;
                        timelogPost=timetable2table(timelogPost); %data for UITable must be table
                        
                        %add 'not in range' to runtime and select events column                        
                        timelogPost = movevars(timelogPost, 'duration', 'Before', 'time');
                        timelogPost = movevars(timelogPost, 'description', 'After', 'duration');
                        timelogPost = movevars(timelogPost, 'retrospective', 'After', 'description');
                        timelogPost = movevars(timelogPost, 'time', 'After', 'retrospective');
                    else 
                        col1={'No entries after end of the experiment'};
                        timelogPost=table(col1);
                        app.timelogTablePre.ColumnWidth='auto';
                        app.timelogTablePre.ColumnName={'No Timelog'};
                    end 
                    
                    % Print tables in gui
                    app.timelogTablePre.RowName='numbered';                    
                    app.timelogTablePre.ColumnName={'Before Start', 'Description', 'Retrospective', 'Time'};
                    
                    app.timelogTablePre.Data=timelogPre;
                    
                    app.timelogTablePost.RowName='numbered';
                    app.timelogTablePost.ColumnName={'After End', 'Description', 'Retrospective', 'Time'};
                    app.timelogTablePost.Data=timelogPost;                    
                    
                    app.hintNoTimelog.Visible = 'off';
                    app.dataVsTimelogPanel.Visible = 'on';
            
                else
                    app.dataVsTimelogPanel.Visible = 'off';
                    app.hintNoTimelog.Visible = 'on';
                end
            catch E
                uialert(app.triaxialTestEvaluationTool, ['An error occured handling the timelog. No timelog for this experiment will be shown.(', E.message, ')'], 'Warning', 'Icon', 'warning');
			end
			
			app.markLoadedExperiments();
			
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar); 

        end

        % Value changed function: selectExperimentSecondary
        function selectExperimentNoSecondary(app, event)
            
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperimentSecondary.Value;            
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.triaxialTestEvaluationTool, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNoSecondary = value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is working ...');
            %Check if the experiment is allready loaded
            if ~app.db.isExperimentLoaded(app.experimentNoSecondary)
                messageText = strcat('Loading dataset for experiment no. ', string(app.experimentNoSecondary), ' from database. Depending on the duration of the experiment this might take a while.');
                app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                
                if(~app.db.loadExperiment(app.experimentNoSecondary))
                    uialert(app.triaxialTestEvaluationTool, 'Error loading experiments data.', 'Error');
                    close(app.loadingProgBar);
                    return;
                end
                
            end
            
            try
                %Get metadata from database
                messageText = strcat('Loading metadata for experiment no. ', string(app.experimentNoSecondary));
                app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
                metaData = app.db.getMetaData((app.experimentNoSecondary));
               
 
                %Get specimen and rock data from database
                specimenData = app.db.getSpecimenData(app.experimentNoSecondary);

            catch E
                uialert(app.triaxialTestEvaluationTool, ['An error occured while loading specimen- oder metadata for the selected experiment.(', E.message, ')'], 'warning', 'Icon', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Rock Data Compare Page          
            
            %Show timestep selection after data sucesfully loaded            
            app.selectTimestepSecondary.Enable = 'on';
            app.selectTimestepSecondary.Value = 2;
            app.y1AxisSecondaryComparisonTab.Enable = 'on';
            app.y1AxisSecondaryComparisonTab.Value = 'permeabilityCoeff';
            app.y2AxisSecondaryComparisonTab.Enable = 'on';
            
            app.xAxisSecondaryComparisonTab.Enable = 'on';
            app.xAxisSecondaryComparisonTab.Value = 'runtime';
            app.y1AxisSecondaryComparisonTab.Enable = 'on';
            app.y2AxisSecondaryComparisonTab.Enable = 'on';
            app.legendSecondaryComparisonTab.Enable = 'on';
            
            app.graphComparisonTabReload_Secondary;
            % app.graphComparisonTab2Reload_Secondary;
            
			% Add actual experiment to loaded experiments table on merge tab
			app.updateExperimentsOverlappingTable()
			
			app.markLoadedExperiments();
			
			
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);
        end

        % Callback function
        function selectTimestepSecondaryValueChanged(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestepSecondaryValueChanged.Value == 0)
                uialert(app.triaxialTestEvaluationTool,'No timestep was selected.','Warning', 'Icon', 'warning');
                return;
            end
            
            app.graphSecondaryComparisonTab; 
        end

        % Cell edit callback: timelogTableActual
        function timelogTicksChange(app, event)
            indices = event.Indices;   %row and column numer of event    
             
            app.showTimelogTick(app.graphTimelogTab, app.timelogTableActual, indices(1));
        end

        % Button pushed function: clearEventsButton
        function clearTimelogTicks(app, event)
            %uncheck select events column
            app.timelogTableActual.Data{:,1} = 0;
            
            %Reload graph
            app.graphTimelogTabReload;

            
        end

        % Callback function
        function graphComparisonTabReload_Primary(app, event)
           
            y1Selection = app.y1AxisPrimaryComparisonTab.Value;
            y2Selection = app.y2AxisPrimaryComparisonTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection, app.graphPrimaryComparisonTab, true); 
        end

        % Value changed function: selectTimestepSecondary
        function graphComparisonTabReload_Secondary(app, event)
           
            y1Selection = app.y1AxisSecondaryComparisonTab.Value;
            y2Selection = app.y2AxisSecondaryComparisonTab.Value;
            timestepSelection = app.selectTimestepSecondary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoSecondary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection, app.graphSecondaryComparisonTab, true); 

        end

        % Value changed function: xAxisPrimaryComparisonTab, 
        % y1AxisPrimaryComparisonTab, y2AxisPrimaryComparisonTab
        function graphComparisonTab2Reload_Primary(app, event)
           
            xSelection = app.xAxisPrimaryComparisonTab.Value;
            y1Selection = app.y1AxisPrimaryComparisonTab.Value;
            y2Selection = app.y2AxisPrimaryComparisonTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoPrimary, ...
                xSelection, y1Selection, y2Selection, ...
                timestepSelection, app.graphPrimaryComparisonTab, true);    
			
			app.legendPrimaryComparisonTabValueChanged()
        end

        % Value changed function: xAxisSecondaryComparisonTab, 
        % y1AxisSecondaryComparisonTab, y2AxisSecondaryComparisonTab
        function graphComparisonTab2Reload_Secondary(app, event)
            xSelection = app.xAxisSecondaryComparisonTab.Value;
            y1Selection = app.y1AxisSecondaryComparisonTab.Value;
            y2Selection = app.y2AxisSecondaryComparisonTab.Value;
            timestepSelection = app.selectTimestepSecondary.Value;
            
            app.setGraphDataSet(...
                app.experimentNoSecondary, ...
                xSelection, y1Selection, y2Selection, ...
                timestepSelection, app.graphSecondaryComparisonTab, true);   
			
			app.legendSecondaryComparisonTabValueChanged()
            
        end

        % Button pushed function: ExportData
        function ExportData2ExcelButtonPushed(app, event)
            y1Selection = app.y1AxisPrimaryComparisonTab.Value;
            y2Selection = app.y2AxisPrimaryComparisonTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
            app.export2csv(...
                app.experimentNoPrimary, ...
                'runtime', y1Selection, y2Selection, ...
                timestepSelection);   
            
        end

        % Button pushed function: btnExportGraphs
        function btnExportGraphsPushed(app, event)
            app.exportGraphs()
        end

        % Value changed function: legendPrimaryComparisonTab
        function legendPrimaryComparisonTabValueChanged(app, event)
            value = app.legendPrimaryComparisonTab.Value;
            app.graphPrimaryComparisonTab.Legend.Location = value;
			
        end

        % Value changed function: legendSecondaryComparisonTab
        function legendSecondaryComparisonTabValueChanged(app, event)
            value = app.legendSecondaryComparisonTab.Value;
            app.graphSecondaryComparisonTab.Legend.Location = value;
			
        end

        % Button pushed function: alignX
        function alignXButtonPushed(app, event)
			try
				xMin = min(min(app.graphSecondaryComparisonTab.XLim, app.graphPrimaryComparisonTab.XLim));
				xMax = max(max(app.graphSecondaryComparisonTab.XLim, app.graphPrimaryComparisonTab.XLim));
				xLim = [xMin, xMax];
				
				app.graphSecondaryComparisonTab.XLim = xLim;
				app.graphPrimaryComparisonTab.XLim = xLim;
			catch E
                    warning()
                    uialert(app.triaxialTestEvaluationTool, [E.message], 'Warning', 'Icon', 'warning');                
			end
			
        end

        % Button pushed function: alignY
        function alignYButtonPushed(app, event)
			try
				y1Min = min(min(app.graphPrimaryComparisonTab.YAxis(1).Limits, app.graphSecondaryComparisonTab.YAxis(1).Limits));
				y1Max = max(max(app.graphPrimaryComparisonTab.YAxis(1).Limits, app.graphSecondaryComparisonTab.YAxis(1).Limits));
				y1Lim = [y1Min, y1Max];
				
				app.graphPrimaryComparisonTab.YAxis(1).Limits = y1Lim;
				app.graphSecondaryComparisonTab.YAxis(1).Limits = y1Lim;
				
				y2Min = min(min(app.graphPrimaryComparisonTab.YAxis(2).Limits, app.graphSecondaryComparisonTab.YAxis(2).Limits));
				y2Max = max(max(app.graphPrimaryComparisonTab.YAxis(2).Limits, app.graphSecondaryComparisonTab.YAxis(2).Limits));
				y2Lim = [y2Min, y2Max];
				
				app.graphPrimaryComparisonTab.YAxis(2).Limits = y2Lim;
				app.graphSecondaryComparisonTab.YAxis(2).Limits = y2Lim;
			catch E
                    warning()
                    uialert(app.triaxialTestEvaluationTool, [E.message], 'Warning', 'Icon', 'warning');                
			end
			
        end

        % Callback function: EndSlider, RefreshGraphButton, 
        % StartSlider, xAxisOverlappingComparisonTab, 
        % y1AxisOverlappingComparisonTab, 
        % y2AxisOverlappingComparisonTab
        function OverlappingComparisonValuesChanged(app, event)
			xSelection = app.xAxisOverlappingComparisonTab.Value;
            y1Selection = app.y1AxisOverlappingComparisonTab.Value;
            y2Selection = app.y2AxisOverlappingComparisonTab.Value;
            timestepSelection = app.selectTimestepPrimary.Value;
            
			experimentsList = cell2table(app.experimentsOverlappingTable.Data);
			toDelete = experimentsList.Var1 == false;
			experimentsList(toDelete,:) = [];
			experimentsList = experimentsList.Var2;
			
            app.setGraphDataSet(...
                experimentsList, ...
                xSelection, y1Selection, y2Selection, ...
                timestepSelection, app.graphOverlappingComparisonTab, true); 
			
			app.legendOverlappingComparisonTabValueChanged();
			
			app.getStatistics(...
				experimentsList, ...
                xSelection, y1Selection, y2Selection, ...
                timestepSelection, app.graphOverlappingComparisonTab)
			
			startRange = app.StartSlider.Limits(2) - app.StartSlider.Limits(1);
			endRange = app.EndSlider.Limits(2) - app.EndSlider.Limits(1);
			
			xLimit = app.graphOverlappingComparisonTab.XLim;

			if ~strcmp(app.startSliderValue.Value, char(xLimit(2)/startRange*app.StartSlider.Value))
				if isduration(xLimit(2))
					app.startSliderValue.Value = datestr(xLimit(2)/startRange*app.StartSlider.Value, 'HH:MM:SS');
				else
					app.startSliderValue.Value = sprintf('%g', xLimit(2)/startRange*app.StartSlider.Value);
				end
			end
			
			if ~strcmp(app.endSliderValue.Value, char(xLimit(2)/endRange*app.EndSlider.Value))
				if isduration(xLimit(2))
					app.endSliderValue.Value = datestr(xLimit(2)/endRange*app.EndSlider.Value, 'HH:MM:SS');	
				else
					app.endSliderValue.Value = sprintf('%g', xLimit(2)/endRange*app.EndSlider.Value);
				end
			end
			
        end

        % Value changed function: legendOverlappingComparisonTab
        function legendOverlappingComparisonTabValueChanged(app, event)
            value = app.legendOverlappingComparisonTab.Value;
			if ~isempty(app.graphOverlappingComparisonTab.Legend)
				app.graphOverlappingComparisonTab.Legend.Location = value;	
			end
        end

        % Close request function: triaxialTestEvaluationTool
        function GUICloseRequest(app, event)
            
			% Close and delete all subwindows
			fn = fieldnames(app.dialogApps);
			for k=1:numel(fn)
				delete(app.dialogApps.(fn{k}))
			end
			
			% Delete loaded experiments 
			app.db.clearExperimentCache;
			
			% Close GUI
			delete(app)
            
        end

        % Callback function: ExperimentsListFilterButton, 
        % SelectFilterMenu
        function openExperimentsFilter(app, event)
            value = app.ExperimentsListFilterButton.Value;
            
			% Check button state
			if strcmp(event.EventName, 'ValueChanged')
				if value
					% Button is down: show filter dialog
					app.dialogApps.ExperimentsFilter = subExperimentsFilter(app);			
					
				else
					% Button is up: close and delete filter dialog
					delete(app.dialogApps.ExperimentsFilter)
				end
			else
				app.dialogApps.ExperimentsFilter = subExperimentsFilter(app);
			end
				
        end

        % Callback function: FigureSettingsButton, SettingsMenu
        function openFigureSettings(app, event)
            value = app.FigureSettingsButton.Value;
            
			% Check button state
			if strcmp(event.EventName, 'ValueChanged')
				if value
					% Button is down: show filter dialog
					app.dialogApps.FigureSettings = subFigureSettings(app, app.graphOverlappingComparisonTab);			
					
				else
					% Button is up: close and delete filter dialog
					delete(app.dialogApps.FigureSettings)
				end
			else
				app.dialogApps.FigureSettings = subFigureSettings(app, app.graphOverlappingComparisonTab);
			end
            
        end

        % Button pushed function: ExperimentsListLoadSelected
        function experimentsListLoadSelectedPressed(app, event)
            
			titleText = strcat('Please wait ... Triaxial Test Evaluation Tool is catching ...');
            messageText = strcat('Loading selected datasets');
            app.loadingProgBar = uiprogressdlg(app.triaxialTestEvaluationTool, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');
			
			experimentsList = [];
			
			for k=1:height(app.experimentsOverviewTable.Data)
				if app.experimentsOverviewTable.Data.loadList(k) == true
					experimentsList = [experimentsList app.experimentsOverviewTable.Data.experimentNo(k)];
				end
			end
			
			app.db.loadExperiment(experimentsList);
			app.markLoadedExperiments();
			
			close(app.loadingProgBar); 
            
        end

        % Callback function
        function StatSliderValueChanged(app, event)
            startRange = app.StartSlider.Limits(2) - app.StartSlider.Limits(1);
			endRange = app.EndSlider.Limits(2) - app.EndSlider.Limits(1);
			
			xLimit = app.graphOverlappingComparisonTab.XLim;

			if ~strcmp(app.startSliderValue.Value, char(xLimit(2)/startRange*app.StartSlider.Value))
				if isduration(xLimit(2))
					app.startSliderValue.Value = datestr(xLimit(2)/startRange*app.StartSlider.Value, 'HH:MM:SS');
				else
					app.startSliderValue.Value = char(xLimit(2)/startRange*app.StartSlider.Value);
				end
			end
			
			if ~strcmp(app.endSliderValue.Value, char(xLimit(2)/endRange*app.EndSlider.Value))
				if isduration(xLimit(2))
					app.endSliderValue.Value = datestr(xLimit(2)/endRange*app.EndSlider.Value, 'HH:MM:SS');	
				else
					app.endSliderValue.Value = char(xLimit(2)/endRange*app.EndSlider.Value);
				end
			end
			
			app.getStatistics(app.graphOverlappingComparisonTab)
            
        end

        % Callback function
        function StatSliderValueChanging(app, event)
			startRange = app.StartSlider.Limits(2) - app.StartSlider.Limits(1);
			endRange = app.EndSlider.Limits(2) - app.EndSlider.Limits(1);
			
			xLimit = app.graphOverlappingComparisonTab.XLim;

			if ~strcmp(app.startSliderValue.Value, char(xLimit(2)/startRange*app.StartSlider.Value))
				if isduration(xLimit(2))
					app.startSliderValue.Value = datestr(xLimit(2)/startRange*app.StartSlider.Value, 'HH:MM:SS');
				else
					app.startSliderValue.Value = char(xLimit(2)/startRange*app.StartSlider.Value);
				end
			end
			
			if ~strcmp(app.endSliderValue.Value, char(xLimit(2)/endRange*app.EndSlider.Value))
				if isduration(xLimit(2))
					app.endSliderValue.Value = datestr(xLimit(2)/endRange*app.EndSlider.Value, 'HH:MM:SS');	
				else
					app.endSliderValue.Value = char(xLimit(2)/endRange*app.EndSlider.Value);
				end
			end
            
        end

        % Value changed function: endSliderValue, startSliderValue
        function SliderValueValueChanged(app, event)
            startRange = app.StartSlider.Limits(2) - app.StartSlider.Limits(1);
			endRange = app.EndSlider.Limits(2) - app.EndSlider.Limits(1);
			
			xLimit = app.graphOverlappingComparisonTab.XLim;

			if ~strcmp(app.startSliderValue.Value, char(xLimit(2)/startRange*app.StartSlider.Value))
				if isduration(xLimit(2))
					if duration(app.startSliderValue.Value) == 0
						app.StartSlider.Value = 0;
					else
						app.StartSlider.Value = duration(app.startSliderValue.Value, 'InputFormat', 'hh:mm:ss')/xLimit(2)*100/100*startRange;
					end
				else
					app.StartSlider.Value = str2double(app.startSliderValue.Value)/xLimit(2)*100/100*startRange;
				end
			end
			
			if ~strcmp(app.endSliderValue.Value, char(xLimit(2)/endRange*app.EndSlider.Value))
				if isduration(xLimit(2))
					if duration(app.endSliderValue.Value) == 0
						app.EndSlider.Value = 0;
					else
						app.EndSlider.Value = duration(app.endSliderValue.Value, 'InputFormat', 'hh:mm:ss')/xLimit(2)*100/100*endRange;
					end
				else
					app.EndSlider.Value = str2double(app.endSliderValue.Value)/xLimit(2)*100/100*endRange;
				end
			end
			
			app.OverlappingComparisonValuesChanged()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.triaxialTestEvaluationTool = uifigure('Visible', 'off');
            app.triaxialTestEvaluationTool.Name = 'Triaxial Experiments Evaluation Tool';
            app.triaxialTestEvaluationTool.CloseRequestFcn = createCallbackFcn(app, @GUICloseRequest, true);
            app.triaxialTestEvaluationTool.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.triaxialTestEvaluationTool);
            app.LeftPanel = uipanel(app.GridLayout);
            app.btnExportGraphs = uibutton(app.LeftPanel, 'push');
            app.btnExportGraphs.ButtonPushedFcn = createCallbackFcn(app, @btnExportGraphsPushed, true);
            app.btnExportGraphs.Enable = 'off';
            app.btnExportGraphs.Text = 'Export all graphs as shown';
            app.MainExperimentPanel = uipanel(app.LeftPanel);
            app.MainExperimentPanel.Title = 'Main Experiment';
            app.SelectExperimentLabel = uilabel(app.MainExperimentPanel);
            app.SelectExperimentLabel.Text = 'Select Experiment:';
            app.selectExperimentPrimary = uidropdown(app.MainExperimentPanel);
            app.selectExperimentPrimary.Items = {'- no selection -'};
            app.selectExperimentPrimary.ItemsData = {'0'};
            app.selectExperimentPrimary.ValueChangedFcn = createCallbackFcn(app, @selectExperimentNoPrimary, true);
            app.selectExperimentPrimary.Enable = 'off';
            app.selectExperimentPrimary.Value = '0';
            app.TimestepminLabel_2 = uilabel(app.MainExperimentPanel);
            app.TimestepminLabel_2.Text = 'Timestep (min):';
            app.selectTimestepPrimary = uidropdown(app.MainExperimentPanel);
            app.selectTimestepPrimary.Items = {'- no selection -'};
            app.selectTimestepPrimary.ItemsData = {'0'};
            app.selectTimestepPrimary.ValueChangedFcn = createCallbackFcn(app, @primaryTimestepChange, true);
            app.selectTimestepPrimary.Enable = 'off';
            app.selectTimestepPrimary.Value = '0';
            app.primaryRockDataTable = uitable(app.MainExperimentPanel);
            app.primaryRockDataTable.ColumnName = {'Value'};
            app.primaryRockDataTable.RowName = {};
            app.primaryRockDataTable.ColumnSortable = true;
            app.DescriptionLabel = uilabel(app.MainExperimentPanel);
            app.DescriptionLabel.Text = 'Description';
            app.CommentLabel = uilabel(app.MainExperimentPanel);
            app.CommentLabel.Text = 'Comment';
            app.mainDescription = uitextarea(app.MainExperimentPanel);
            app.mainDescription.Editable = 'off';
            app.mainComment = uitextarea(app.MainExperimentPanel);
            app.mainComment.Editable = 'off';
            app.ComparisonExperimentPanel_2 = uipanel(app.LeftPanel);
            app.ComparisonExperimentPanel_2.Title = 'Comparison Experiment';
            app.TimestepminLabel = uilabel(app.ComparisonExperimentPanel_2);
            app.TimestepminLabel.Text = 'Timestep (min):';
            app.selectTimestepSecondary = uidropdown(app.ComparisonExperimentPanel_2);
            app.selectTimestepSecondary.Items = {'- no selection -'};
            app.selectTimestepSecondary.ItemsData = {'0'};
            app.selectTimestepSecondary.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTabReload_Secondary, true);
            app.selectTimestepSecondary.Enable = 'off';
            app.selectTimestepSecondary.Value = '0';
            app.selectExperimentSecondary = uidropdown(app.ComparisonExperimentPanel_2);
            app.selectExperimentSecondary.Items = {'- no selection -'};
            app.selectExperimentSecondary.ItemsData = {'0'};
            app.selectExperimentSecondary.ValueChangedFcn = createCallbackFcn(app, @selectExperimentNoSecondary, true);
            app.selectExperimentSecondary.Enable = 'off';
            app.selectExperimentSecondary.Value = '0';
            app.SelectExperimentLabel_2 = uilabel(app.ComparisonExperimentPanel_2);
            app.SelectExperimentLabel_2.Text = 'Select Experiment:';
            app.RightPanel = uipanel(app.GridLayout);
            app.tabGroup = uitabgroup(app.RightPanel);
            app.experimentsOverviewTab = uitab(app.tabGroup);
            app.experimentsOverviewTab.Title = 'Experiments List';
            app.experimentsOverviewTable = uitable(app.experimentsOverviewTab);
            app.experimentsOverviewTable.ColumnName = {''; '#'; 'Short'; 'Begin'; 'End'; 'Duration'; 'Assistant'};
            app.experimentsOverviewTable.RowName = {};
            app.experimentsOverviewTable.ColumnSortable = true;
            app.experimentsOverviewTable.ColumnEditable = [true false];
            app.ExperimentsListFilterButton = uibutton(app.experimentsOverviewTab, 'state');
            app.ExperimentsListFilterButton.ValueChangedFcn = createCallbackFcn(app, @openExperimentsFilter, true);
            app.ExperimentsListFilterButton.Enable = 'off';
            app.ExperimentsListFilterButton.Text = 'Filter';
            app.ExperimentsListLoadSelected = uibutton(app.experimentsOverviewTab, 'push');
            app.ExperimentsListLoadSelected.ButtonPushedFcn = createCallbackFcn(app, @experimentsListLoadSelectedPressed, true);
            app.ExperimentsListLoadSelected.Enable = 'off';
            app.ExperimentsListLoadSelected.Tooltip = {'Set checkbox on experiments to be loaded sequentially.'};
            app.ExperimentsListLoadSelected.Text = '(Re)Load Selected';
            app.dataAndOutliersTab = uitab(app.tabGroup);
            app.dataAndOutliersTab.Title = 'Data Overview';
            app.dataAndWeightPanel = uipanel(app.dataAndOutliersTab);
            app.dataAndWeightPanel.Title = 'Overview';
            app.graphOverview = uiaxes(app.dataAndWeightPanel);
            title(app.graphOverview, 'Title')
            xlabel(app.graphOverview, 'X')
            ylabel(app.graphOverview, 'Y')
            app.graphWeight = uiaxes(app.dataAndWeightPanel);
            title(app.graphWeight, 'Title')
            xlabel(app.graphWeight, 'X')
            ylabel(app.graphWeight, 'Y')
            app.graphFluidFlow = uiaxes(app.dataAndWeightPanel);
            title(app.graphFluidFlow, 'Title')
            xlabel(app.graphFluidFlow, 'X')
            ylabel(app.graphFluidFlow, 'Y')
            app.graphDeformation = uiaxes(app.dataAndWeightPanel);
            title(app.graphDeformation, 'Title')
            xlabel(app.graphDeformation, 'X')
            ylabel(app.graphDeformation, 'Y')
            app.timelogTab = uitab(app.tabGroup);
            app.timelogTab.Title = 'Data vs. Timelog';
            app.dataVsTimelogPanel = uipanel(app.timelogTab);
            app.graphTimelogTab = uiaxes(app.dataVsTimelogPanel);
            title(app.graphTimelogTab, '')
            xlabel(app.graphTimelogTab, {'Runtime (hh:mm)'; ''})
            ylabel(app.graphTimelogTab, {'Permeability'; ''})
            app.DisplayonrightaxisLabel = uilabel(app.dataVsTimelogPanel);
            app.DisplayonrightaxisLabel.Text = 'Display on right axis:';
            app.y2AxisTimelogTab = uidropdown(app.dataVsTimelogPanel);
            app.y2AxisTimelogTab.Items = {'- no selection -'};
            app.y2AxisTimelogTab.ItemsData = {'0'};
            app.y2AxisTimelogTab.ValueChangedFcn = createCallbackFcn(app, @graphTimelogTabReload, true);
            app.y2AxisTimelogTab.Value = '0';
            app.timelogPanel = uipanel(app.dataVsTimelogPanel);
            app.timelogPanel.Title = 'Timelog';
            app.timelogTableActual = uitable(app.timelogPanel);
            app.timelogTableActual.ColumnName = {'column 1'};
            app.timelogTableActual.RowName = {};
            app.timelogTableActual.ColumnEditable = [true false false false false];
            app.timelogTableActual.CellEditCallback = createCallbackFcn(app, @timelogTicksChange, true);
            app.timelogTablePre = uitable(app.timelogPanel);
            app.timelogTablePre.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePre.RowName = {};
            app.timelogTablePost = uitable(app.timelogPanel);
            app.timelogTablePost.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePost.RowName = {};
            app.clearEventsButton = uibutton(app.timelogPanel, 'push');
            app.clearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @clearTimelogTicks, true);
            app.clearEventsButton.Enable = 'off';
            app.clearEventsButton.Text = 'Clear marked events';
            app.DisplayonleftaxisLabel = uilabel(app.dataVsTimelogPanel);
            app.DisplayonleftaxisLabel.Text = 'Display on left axis:';
            app.y1AxisTimelogTab = uidropdown(app.dataVsTimelogPanel);
            app.y1AxisTimelogTab.Items = {'- no selection -'};
            app.y1AxisTimelogTab.ItemsData = {'0'};
            app.y1AxisTimelogTab.ValueChangedFcn = createCallbackFcn(app, @graphTimelogTabReload, true);
            app.y1AxisTimelogTab.Value = '0';
            app.hintNoTimelog = uilabel(app.timelogTab);
            app.hintNoTimelog.Visible = 'off';
            app.hintNoTimelog.Text = 'No Timelog available. For experiment evaluation use ''Compare Experiments'' tab.';
            app.comparisonTab = uitab(app.tabGroup);
            app.comparisonTab.Title = 'Compare Experiments';
            app.MainExperimentPanel_2 = uipanel(app.comparisonTab);
            app.MainExperimentPanel_2.Title = 'Main Experiment';
            app.graphPrimaryComparisonTab = uiaxes(app.MainExperimentPanel_2);
            title(app.graphPrimaryComparisonTab, '')
            xlabel(app.graphPrimaryComparisonTab, 'X')
            ylabel(app.graphPrimaryComparisonTab, 'Y')
            app.YAxisLeftLabel_4 = uilabel(app.MainExperimentPanel_2);
            app.YAxisLeftLabel_4.Text = 'Y-Axis (Left):';
            app.y1AxisPrimaryComparisonTab = uidropdown(app.MainExperimentPanel_2);
            app.y1AxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Primary, true);
            app.XAxisLabel_3 = uilabel(app.MainExperimentPanel_2);
            app.XAxisLabel_3.Text = 'X-Axis:';
            app.xAxisPrimaryComparisonTab = uidropdown(app.MainExperimentPanel_2);
            app.xAxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Primary, true);
            app.YAxisRightLabel_3 = uilabel(app.MainExperimentPanel_2);
            app.YAxisRightLabel_3.Text = 'Y-Axis (Right):';
            app.y2AxisPrimaryComparisonTab = uidropdown(app.MainExperimentPanel_2);
            app.y2AxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Primary, true);
            app.LegendLabel = uilabel(app.MainExperimentPanel_2);
            app.LegendLabel.Text = 'Legend:';
            app.legendPrimaryComparisonTab = uidropdown(app.MainExperimentPanel_2);
            app.legendPrimaryComparisonTab.Items = {'auto,', 'upper left', 'upper right', 'lower left', 'lower right'};
            app.legendPrimaryComparisonTab.ItemsData = {'best,', 'northwest', 'northeast', 'southwest', 'southeast'};
            app.legendPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @legendPrimaryComparisonTabValueChanged, true);
            app.legendPrimaryComparisonTab.Value = 'best,';
            app.ExportData = uibutton(app.MainExperimentPanel_2, 'push');
            app.ExportData.ButtonPushedFcn = createCallbackFcn(app, @ExportData2ExcelButtonPushed, true);
            app.ExportData.Text = 'Export Data';
            app.ComparisonExperimentPanel = uipanel(app.comparisonTab);
            app.ComparisonExperimentPanel.Title = 'Comparison Experiment';
            app.YAxisLeftLabel_3 = uilabel(app.ComparisonExperimentPanel);
            app.YAxisLeftLabel_3.Text = 'Y-Axis (Left):';
            app.y1AxisSecondaryComparisonTab = uidropdown(app.ComparisonExperimentPanel);
            app.y1AxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Secondary, true);
            app.y1AxisSecondaryComparisonTab.Enable = 'off';
            app.graphSecondaryComparisonTab = uiaxes(app.ComparisonExperimentPanel);
            title(app.graphSecondaryComparisonTab, '')
            xlabel(app.graphSecondaryComparisonTab, 'X')
            ylabel(app.graphSecondaryComparisonTab, 'Y')
            app.XAxisLabel_2 = uilabel(app.ComparisonExperimentPanel);
            app.XAxisLabel_2.Text = 'X-Axis:';
            app.xAxisSecondaryComparisonTab = uidropdown(app.ComparisonExperimentPanel);
            app.xAxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Secondary, true);
            app.xAxisSecondaryComparisonTab.Enable = 'off';
            app.YAxisRightLabel_2 = uilabel(app.ComparisonExperimentPanel);
            app.YAxisRightLabel_2.Text = 'Y-Axis (Right):';
            app.y2AxisSecondaryComparisonTab = uidropdown(app.ComparisonExperimentPanel);
            app.y2AxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @graphComparisonTab2Reload_Secondary, true);
            app.y2AxisSecondaryComparisonTab.Enable = 'off';
            app.LegendLabel_2 = uilabel(app.ComparisonExperimentPanel);
            app.LegendLabel_2.Text = 'Legend:';
            app.legendSecondaryComparisonTab = uidropdown(app.ComparisonExperimentPanel);
            app.legendSecondaryComparisonTab.Items = {'auto', 'upper left', 'upper right', 'lower left', 'lower right'};
            app.legendSecondaryComparisonTab.ItemsData = {'best', 'northwest', 'northeast', 'southwest', 'southeast'};
            app.legendSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @legendSecondaryComparisonTabValueChanged, true);
            app.legendSecondaryComparisonTab.Enable = 'off';
            app.legendSecondaryComparisonTab.Value = 'northeast';
            app.alignX = uibutton(app.ComparisonExperimentPanel, 'push');
            app.alignX.ButtonPushedFcn = createCallbackFcn(app, @alignXButtonPushed, true);
            app.alignX.Text = 'Align X-Axis';
            app.alignY = uibutton(app.ComparisonExperimentPanel, 'push');
            app.alignY.ButtonPushedFcn = createCallbackFcn(app, @alignYButtonPushed, true);
            app.alignY.Text = 'Align Y-Axis';
            app.overlappingComparisonTab = uitab(app.tabGroup);
            app.overlappingComparisonTab.Title = 'Merge Experiments';
            app.overlappingComparisonPanel = uipanel(app.overlappingComparisonTab);
            app.graphOverlappingComparisonTab = uiaxes(app.overlappingComparisonPanel);
            title(app.graphOverlappingComparisonTab, '')
            xlabel(app.graphOverlappingComparisonTab, 'X')
            ylabel(app.graphOverlappingComparisonTab, 'Y')
            app.experimentsOverlappingTable = uitable(app.overlappingComparisonPanel);
            app.experimentsOverlappingTable.ColumnName = {''; 'No'; 'Name'};
            app.experimentsOverlappingTable.RowName = {};
            app.experimentsOverlappingTable.ColumnSortable = true;
            app.experimentsOverlappingTable.ColumnEditable = [true false false];
            app.YAxisLeftLabel_5 = uilabel(app.overlappingComparisonPanel);
            app.YAxisLeftLabel_5.Text = 'Y-Axis (Left):';
            app.y1AxisOverlappingComparisonTab = uidropdown(app.overlappingComparisonPanel);
            app.y1AxisOverlappingComparisonTab.ValueChangedFcn = createCallbackFcn(app, @OverlappingComparisonValuesChanged, true);
            app.XAxisLabel_4 = uilabel(app.overlappingComparisonPanel);
            app.XAxisLabel_4.Text = 'X-Axis:';
            app.xAxisOverlappingComparisonTab = uidropdown(app.overlappingComparisonPanel);
            app.xAxisOverlappingComparisonTab.ValueChangedFcn = createCallbackFcn(app, @OverlappingComparisonValuesChanged, true);
            app.YAxisRightLabel_4 = uilabel(app.overlappingComparisonPanel);
            app.YAxisRightLabel_4.Text = 'Y-Axis (Right):';
            app.y2AxisOverlappingComparisonTab = uidropdown(app.overlappingComparisonPanel);
            app.y2AxisOverlappingComparisonTab.ValueChangedFcn = createCallbackFcn(app, @OverlappingComparisonValuesChanged, true);
            app.LegendLabel_3 = uilabel(app.overlappingComparisonPanel);
            app.LegendLabel_3.Text = 'Legend:';
            app.legendOverlappingComparisonTab = uidropdown(app.overlappingComparisonPanel);
            app.legendOverlappingComparisonTab.Items = {'auto', 'upper left', 'upper right', 'lower left', 'lower right'};
            app.legendOverlappingComparisonTab.ItemsData = {'best', 'northwest', 'northeast', 'southwest', 'southeast'};
            app.legendOverlappingComparisonTab.ValueChangedFcn = createCallbackFcn(app, @legendOverlappingComparisonTabValueChanged, true);
            app.legendOverlappingComparisonTab.Value = 'best';
            app.RefreshGraphButton = uibutton(app.overlappingComparisonPanel, 'push');
            app.RefreshGraphButton.ButtonPushedFcn = createCallbackFcn(app, @OverlappingComparisonValuesChanged, true);
            app.RefreshGraphButton.Text = 'Refresh Graph';
            app.FigureSettingsButton = uibutton(app.overlappingComparisonPanel, 'state');
            app.FigureSettingsButton.ValueChangedFcn = createCallbackFcn(app, @openFigureSettings, true);
            app.FigureSettingsButton.Text = 'Figure Settings';
            app.StatisticsTextAreaLabel = uilabel(app.overlappingComparisonPanel);
            app.StatisticsTextAreaLabel.Text = 'Statistics';
            app.StatisticsResults = uitextarea(app.overlappingComparisonPanel);
            app.StatisticsResults.Editable = 'off';
            app.EndSliderLabel = uilabel(app.overlappingComparisonPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.overlappingComparisonPanel);
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @OverlappingComparisonValuesChanged, true);
            app.EndSlider.Value = 100;
            app.StartSliderLabel = uilabel(app.overlappingComparisonPanel);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.overlappingComparisonPanel);
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {''};
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @OverlappingComparisonValuesChanged, true);
            app.StartSlider.MinorTicks = [];
            app.startSliderValue = uieditfield(app.overlappingComparisonPanel, 'text');
            app.startSliderValue.ValueChangedFcn = createCallbackFcn(app, @SliderValueValueChanged, true);
            app.startSliderValue.Value = '00:00:00';
            app.endSliderValue = uieditfield(app.overlappingComparisonPanel, 'text');
            app.endSliderValue.ValueChangedFcn = createCallbackFcn(app, @SliderValueValueChanged, true);
            app.endSliderValue.Value = '00:00:00';
            app.ExperimentsMenu = uicontextmenu(app.triaxialTestEvaluationTool);
            % Assign app.ExperimentsMenu
            app.experimentsOverviewTable.ContextMenu = app.ExperimentsMenu;
            app.SelectFilterMenu = uimenu(app.ExperimentsMenu);
            app.SelectFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @openExperimentsFilter, true);
            app.SelectFilterMenu.Text = 'Select Filter';
            app.FigureMenu = uicontextmenu(app.triaxialTestEvaluationTool);
            % Assign app.FigureMenu
            app.overlappingComparisonPanel.ContextMenu = app.FigureMenu;
            app.graphOverlappingComparisonTab.ContextMenu = app.FigureMenu;
            app.SettingsMenu = uimenu(app.FigureMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @openFigureSettings, true);
            app.SettingsMenu.Text = 'Settings';
            % Show the figure after all components are created
            app.triaxialTestEvaluationTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.triaxialTestEvaluationTool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.triaxialTestEvaluationTool)
        end
    end
end