classdef subExperimentsFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RocksListBoxLabel      matlab.ui.control.Label
        RocksListBox           matlab.ui.control.ListBox
        AssistantListBoxLabel  matlab.ui.control.Label
        AssistantListBox       matlab.ui.control.ListBox
        ButtonGroup            matlab.ui.container.ButtonGroup
        ShowAllButton          matlab.ui.control.ToggleButton
        PretestsOnlyButton     matlab.ui.control.ToggleButton
        FinaltestsOnlyButton   matlab.ui.control.ToggleButton
        StartDatePickerLabel   matlab.ui.control.Label
        StartDatePicker        matlab.ui.control.DatePicker
        EndDatePickerLabel     matlab.ui.control.Label
        EndDatePicker          matlab.ui.control.DatePicker
        ChooseexperimentstobeshownintheexperimentoverviewLabel  matlab.ui.control.Label
        ResetFilterButton      matlab.ui.control.Button
        TestRigListBoxLabel    matlab.ui.control.Label
        TestRigListBox         matlab.ui.control.ListBox
        ApplyFilterButton      matlab.ui.control.Button
    end

    
    properties (Access = private) 
        callingApp   % Main app object
		filteredList
		originalList
    end
    
    methods (Access = private)
        
        function results = resetFilter(app)
            experimentsList = app.originalList;
			
			app.AssistantListBox.Items = groupsummary(experimentsList,'assistant').assistant;
			app.AssistantListBox.ItemsData = groupsummary(experimentsList,'assistant').assistant;
			app.AssistantListBox.Value = app.AssistantListBox.ItemsData;

			app.TestRigListBox.Items = string(groupsummary(experimentsList,'testRigId').testRigId);
			app.TestRigListBox.ItemsData = groupsummary(experimentsList,'testRigId').testRigId;
			app.TestRigListBox.Value = app.TestRigListBox.ItemsData;
			
			app.StartDatePicker.Value = min(experimentsList.timeStart);
			app.StartDatePicker.DisplayFormat = 'dd.MM.uu';
			
			app.EndDatePicker.Value = max(experimentsList.timeStart);
			app.EndDatePicker.DisplayFormat = 'dd.MM.uu';
			
			app.ShowAllButton.Text = ['Show all (' int2str(height(experimentsList)) ')'];
			app.PretestsOnlyButton.Text = ['Pre test only (' int2str(groupsummary(experimentsList,'preTest').GroupCount(2)) ')'];
			app.FinaltestsOnlyButton.Text = ['Final test only (' int2str(groupsummary(experimentsList,'preTest').GroupCount(1)) ')'];
			app.ShowAllButton.Value = 1;
			
			results = true;
        end
        
        function results = createFilteredList(app)
            % Filter experimentsList to selected data
			experimentsList = app.originalList;
			
			% Check selected assistants and test rigs
			experimentsList(~ismember(experimentsList.assistant,app.AssistantListBox.Value),:)=[];
			experimentsList(~ismember(experimentsList.testRigId,app.TestRigListBox.Value),:)=[];
			
			% Check pre test / final test selection
			if app.PretestsOnlyButton.Value
				experimentsList(~ismember(experimentsList.preTest,1),:)=[];
			elseif app.FinaltestsOnlyButton.Value
				experimentsList(~ismember(experimentsList.preTest,0),:)=[];
			end
			
			% Check selected start and end date
			
			timeStart = datetime(app.StartDatePicker.Value,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS', 'TimeZone', 'UTC');
			timeEnd = datetime(app.EndDatePicker.Value,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS', 'TimeZone', 'UTC');
			
			idx = (experimentsList.timeStart >= timeStart) & (experimentsList.timeEnd <= timeEnd);
			experimentsList = experimentsList(idx,:);
			
			app.filteredList = experimentsList;
			results = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
			app.UIFigure.Name = 'Set Experiment Filter';
			
			% Store main app in property for CloseRequestFcn to use
            app.callingApp = mainApp;
			app.originalList = app.callingApp.experimentOverviewOriginal;
			
			app.resetFilter;
        end

        % Button pushed function: ResetFilterButton
        function ResetFilterButtonPushed(app, event)
            app.resetFilter;
			app.ApplyFilterButton.Text = 'Apply filter';
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            % Apply set filters to experiments overview table in main GUI
			app.createFilteredList;
			experimentsList = app.filteredList;
			
			
			experimentsList = experimentsList(:,[8 1 2 3 4 9 5]);
			app.callingApp.experimentsOverviewTable.Data = experimentsList;
			app.callingApp.markLoadedExperiments();
			
			dropDownListText = ['- no selection -'; strcat(string(experimentsList.experimentNo), ' - ', string(experimentsList.short))];
			dropDownListData = [0; experimentsList.experimentNo];
			app.callingApp.selectExperimentPrimary.Items = dropDownListText;
			app.callingApp.selectExperimentPrimary.ItemsData = dropDownListData;
			app.callingApp.selectExperimentSecondary.Items = dropDownListText;
			app.callingApp.selectExperimentSecondary.ItemsData = dropDownListData;
        end

        % Callback function: AssistantListBox, ButtonGroup, 
        % EndDatePicker, StartDatePicker, TestRigListBox
        function changedSelection(app, event)
            app.createFilteredList;
			
			app.ApplyFilterButton.Text = ['Apply filter (' int2str(height(app.filteredList)) ')'];
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.callingApp.ExperimentsListFilterButton.Value = 0;
			delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.RocksListBoxLabel = uilabel(app.UIFigure);
            app.RocksListBoxLabel.Enable = 'off';
            app.RocksListBoxLabel.Text = 'Rocks';
            app.RocksListBox = uilistbox(app.UIFigure);
            app.RocksListBox.Multiselect = 'on';
            app.RocksListBox.Enable = 'off';
            app.RocksListBox.Tooltip = {'This option is not available yet.'};
            app.RocksListBox.Value = {'Item 1'};
            app.AssistantListBoxLabel = uilabel(app.UIFigure);
            app.AssistantListBoxLabel.Text = 'Assistant';
            app.AssistantListBox = uilistbox(app.UIFigure);
            app.AssistantListBox.Multiselect = 'on';
            app.AssistantListBox.ValueChangedFcn = createCallbackFcn(app, @changedSelection, true);
            app.AssistantListBox.Value = {'Item 1'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changedSelection, true);
            app.ShowAllButton = uitogglebutton(app.ButtonGroup);
            app.ShowAllButton.Text = 'Show all';
            app.ShowAllButton.Value = true;
            app.PretestsOnlyButton = uitogglebutton(app.ButtonGroup);
            app.PretestsOnlyButton.Text = 'Pre tests only';
            app.FinaltestsOnlyButton = uitogglebutton(app.ButtonGroup);
            app.FinaltestsOnlyButton.Text = 'Final tests only';
            app.StartDatePickerLabel = uilabel(app.UIFigure);
            app.StartDatePickerLabel.Text = 'Start';
            app.StartDatePicker = uidatepicker(app.UIFigure);
            app.StartDatePicker.ValueChangedFcn = createCallbackFcn(app, @changedSelection, true);
            app.EndDatePickerLabel = uilabel(app.UIFigure);
            app.EndDatePickerLabel.Text = 'End';
            app.EndDatePicker = uidatepicker(app.UIFigure);
            app.EndDatePicker.ValueChangedFcn = createCallbackFcn(app, @changedSelection, true);
            app.ChooseexperimentstobeshownintheexperimentoverviewLabel = uilabel(app.UIFigure);
            app.ChooseexperimentstobeshownintheexperimentoverviewLabel.Text = {'Choose experiments to be'; 'shown in the experiment'; ' overview.'};
            app.ResetFilterButton = uibutton(app.UIFigure, 'push');
            app.ResetFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterButtonPushed, true);
            app.ResetFilterButton.Text = 'Reset filter';
            app.TestRigListBoxLabel = uilabel(app.UIFigure);
            app.TestRigListBoxLabel.Text = 'Test Rig';
            app.TestRigListBox = uilistbox(app.UIFigure);
            app.TestRigListBox.Multiselect = 'on';
            app.TestRigListBox.ValueChangedFcn = createCallbackFcn(app, @changedSelection, true);
            app.TestRigListBox.Value = {'Item 1'};
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subExperimentsFilter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end